classdef kspace_explorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        kspaceExplorer                 matlab.ui.Figure
        UI_kspace                      matlab.ui.control.UIAxes
        FOVxSliderLabel                matlab.ui.control.Label
        FOVxSlider                     matlab.ui.control.Slider
        KspaceExplorerLabel            matlab.ui.control.Label
        FOVkySliderLabel               matlab.ui.control.Label
        FOVkySlider                    matlab.ui.control.Slider
        FOVySliderLabel                matlab.ui.control.Label
        FOVySlider                     matlab.ui.control.Slider
        UI_kspace_zoom                 matlab.ui.control.UIAxes
        FOVkxSliderLabel               matlab.ui.control.Label
        FOVkxSlider                    matlab.ui.control.Slider
        UI_ispace                      matlab.ui.control.UIAxes
        UI_ispace_zoom                 matlab.ui.control.UIAxes
        KspacedisplayButtonGroup       matlab.ui.container.ButtonGroup
        kMagnitudeButton               matlab.ui.control.RadioButton
        kPhaseButton                   matlab.ui.control.RadioButton
        kMagPhaseButton                matlab.ui.control.RadioButton
        kRealButton                    matlab.ui.control.RadioButton
        kImagButton                    matlab.ui.control.RadioButton
        ImagedisplayButtonGroup        matlab.ui.container.ButtonGroup
        MagnitudeButton                matlab.ui.control.RadioButton
        PhaseButton                    matlab.ui.control.RadioButton
        MagPhaseButton                 matlab.ui.control.RadioButton
        RealButton                     matlab.ui.control.RadioButton
        ImagButton                     matlab.ui.control.RadioButton
        TabGroup                       matlab.ui.container.TabGroup
        DataselectionTab               matlab.ui.container.Tab
        ChooseimageLabel               matlab.ui.control.Label
        ChooseimageDropDown            matlab.ui.control.DropDown
        PhaseEncodingdirButtonGroup    matlab.ui.container.ButtonGroup
        PhaseEncodingVerticalButton    matlab.ui.control.ToggleButton
        PhaseEncodingHorizontalButton  matlab.ui.control.ToggleButton
        ReconstructionoptionsTab       matlab.ui.container.Tab
        AddnoiseLabel                  matlab.ui.control.Label
        AddnoiseKnob                   matlab.ui.control.Knob
        UsezerofillCheckBox            matlab.ui.control.CheckBox
        UseFermiCheckBox               matlab.ui.control.CheckBox
        ParallelimagingTab             matlab.ui.container.Tab
        AccelerationfactorRKnobLabel   matlab.ui.control.Label
        accelerationFactorRKnob        matlab.ui.control.DiscreteKnob
        centerstripSliderLabel         matlab.ui.control.Label
        centerstripSlider              matlab.ui.control.Slider
        ApplyGRAPPAweightsLabel        matlab.ui.control.Label
        ApplyGRAPPAweightsSwitch       matlab.ui.control.ToggleSwitch
        InformationPanel               matlab.ui.container.Panel
        ScanTimeLabel                  matlab.ui.control.Label
        RelativeSNRLabel               matlab.ui.control.Label
        acqMatrixSizeLabel             matlab.ui.control.Label
        recMatrixSizeLabel             matlab.ui.control.Label
        SNRefficiencyLabel             matlab.ui.control.Label
        AuthorTextArea                 matlab.ui.control.TextArea
        WindowLevelPanel               matlab.ui.container.Panel
        WSliderLabel                   matlab.ui.control.Label
        imageWindow                    matlab.ui.control.Slider
        LSliderLabel                   matlab.ui.control.Label
        imageLevel                     matlab.ui.control.Slider
        kWindowLevelPanel              matlab.ui.container.Panel
        WSlider_2Label                 matlab.ui.control.Label
        kspaceWindow                   matlab.ui.control.Slider
        LSlider_2Label                 matlab.ui.control.Label
        kspaceLevel                    matlab.ui.control.Slider
        showOriginalCheckBox           matlab.ui.control.CheckBox
        kycropModeSwitch               matlab.ui.control.Switch
        CalculationstatusLampLabel     matlab.ui.control.Label
        CalculationstatusLamp          matlab.ui.control.Lamp
        kspace1mLabel                  matlab.ui.control.Label
        imagemLabel                    matlab.ui.control.Label
        kxcropModeSwitch               matlab.ui.control.Switch
        ReconMethodButtonGroup         matlab.ui.container.ButtonGroup
        iFFTButton                     matlab.ui.control.ToggleButton
        HomodyneButton                 matlab.ui.control.ToggleButton
        CouldnotfindTextAreaLabel      matlab.ui.control.Label
        CouldnotfindTextArea           matlab.ui.control.TextArea
        QuitButton                     matlab.ui.control.StateButton
    end


    properties (Access = private)
        imageData = [];
        kspaceData = [];
        imagePixelSize = [1 1];
        kspacePixelSize = [1 1];
        acqMatrix = [];
        grappaWeights = [];
        imageDataMenu = [];
        fullDataSize = [];
        phaseEncodingPercentage = 100;
        UI_ispace_DefaultPosition = [];
        UI_kspace_DefaultPosition = [];
        UI_ispace_zoom_DefaultPosition = [];
        UI_kspace_zoom_DefaultPosition = [];        
        effR = 1;
        kspaceSizePreZfill = [];
    end    
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.lampInProgress('Starting up...');
            app.UI_ispace_DefaultPosition = app.UI_ispace.Position;
            app.UI_kspace_DefaultPosition = app.UI_kspace.Position;
            app.UI_ispace_zoom_DefaultPosition = app.UI_ispace_zoom.Position;
            app.UI_kspace_zoom_DefaultPosition = app.UI_kspace_zoom.Position;           

            [mfilepth,fname] = fileparts(which(mfilename));
            pth = [];
            
            if ispc
                userdir = getenv('USERPROFILE'); 
            else
                userdir = getenv('HOME'); 
            end
                        
            pths = {mfilepth, fullfile(userdir,'Downloads'), fullfile(userdir,'Desktop'), userdir};
            
            for j = 1:numel(pths)
                if exist(fullfile(pths{j},[fname,'.mat']), 'file')
                    pth = pths{j}; break;
                end
            end            
            
            
            if isempty(pth)
                imageDataFileContent.imageDataMenu.description = sprintf('Couldn''t find kspace_explorer.mat (%s)', userdir);
                imageDataFileContent.imageDataMenu.data = rand(512);
                app.CouldnotfindTextArea.Visible = 'on';
                app.CouldnotfindTextArea.Value = sprintf('[%s]: %s ', userdir, string(pths));
                app.CouldnotfindTextAreaLabel.Visible = 'on';
                app.CouldnotfindTextAreaLabel.Text = sprintf('Could not find %s', [fname,'.mat']);
            else
                mfile = fullfile(pth,[fname,'.mat']);
                app.kspaceExplorer.Name = sprintf('k-space Explorer [%s]',mfile);              
                imageDataFileContent = load(mfile);
                app.CouldnotfindTextArea.Visible = 'off';
                app.CouldnotfindTextAreaLabel.Visible = 'off';                
            end
            app.imageDataMenu = imageDataFileContent.imageDataMenu;
            
            app.ApplyGRAPPAweightsSwitch.Enable = 'Off';
            app.centerstripSlider.Enable = 'Off';
            
            % setup image data menu
            app.ChooseimageDropDown.Items = {};
            for j = 1:numel(app.imageDataMenu)
                app.ChooseimageDropDown.Items{j} = app.imageDataMenu(j).description;
            end
            app.ChooseimageDropDown.Value = app.ChooseimageDropDown.Items{1};            
            
            % read image data menu
            app.selectData();
            
            % update image and k-space views
            app.displayData();
            
            app.lampDone();
            
        end

        % Selection changed function: KspacedisplayButtonGroup
        function KspacedisplayButtonGroupSelectionChanged(app, event)
            % selectedButton = app.KspacedisplayButtonGroup.SelectedObject;
            app.displayKspaceData();
        end

        % Selection changed function: ImagedisplayButtonGroup
        function ImagedisplayButtonGroupSelectionChanged(app, event)
            % selectedButton = app.ImagedisplayButtonGroup.SelectedObject;
            app.displayImageData();
        end

        % Value changed function: ApplyGRAPPAweightsSwitch
        function ParallelimagingSwitchValueChanged(app, event)
           app.lampInProgress('Calculating...');
           app.dataUpdate();
           app.displayData();
           app.lampDone();           
        end

        % Value changed function: ChooseimageDropDown
        function DropDownValueChanged(app, event)
            % value = app.ChooseimageDropDown.Value;
            % read image data menu
            app.lampInProgress('Reading data...');
            app.selectData();
            app.dataUpdate();
            app.displayData();
            app.lampDone();            
        end

        % Value changed function: FOVxSlider, FOVySlider
        function SliderValueChanged(app, event)
            % value = app.FOVxSlider.Value;
            app.lampInProgress('Calculating...');
            if app.FOVxSlider.Value > 31 && app.FOVxSlider.Value < 35
                app.FOVxSlider.Value = 33.3333333333;
            else
                app.FOVxSlider.Value = 2.5 * round(app.FOVxSlider.Value/2.5);
            end
            if app.FOVySlider.Value > 31 && app.FOVySlider.Value < 35
                app.FOVySlider.Value = 33.3333333333;
            else
                app.FOVySlider.Value = 2.5 * round(app.FOVySlider.Value/2.5);
            end
            app.grappaWeights = [];
            app.dataUpdate();
            app.displayData();
            app.lampDone();            
        end

        % Selection changed function: PhaseEncodingdirButtonGroup
        function PhaseEncodingdirButtonGroupSelectionChanged(app, event)
            % selectedButton = app.PhaseEncodingdirButtonGroup.SelectedObject;
            app.lampInProgress('Calculating...');
            app.grappaWeights = [];
            app.dataUpdate();
            app.displayData();
            app.lampDone();            
        end

        % Value changed function: AddnoiseKnob
        function AddnoiseKnobValueChanged(app, event)
            % value = app.AddnoiseKnob.Value;
            app.lampInProgress('Calculating...');
            app.grappaWeights = [];
            app.dataUpdate();
            app.displayData();
            app.lampDone();            
        end

        % Value changed function: accelerationFactorRKnob
        function accelerationFactorRKnobValueChanged(app, event)
            %value = app.accelerationFactorRKnob.Value;
            app.lampInProgress('Calculating...');
            app.grappaWeights = [];
            app.dataUpdate();
            app.displayData();            
            app.lampDone();            
        end

        % Value changed function: centerstripSlider
        function centerstripSliderValueChanged(app, event)
            % value = app.centerstripSlider.Value;
            app.lampInProgress('Calculating...');
            app.centerstripSlider.Value = round(app.centerstripSlider.Value);
            app.grappaWeights = [];
            app.dataUpdate();
            app.displayData();
            app.lampDone();
        end

        % Value changed function: FOVkxSlider, FOVkySlider
        function kSliderValueChanged(app, event)
            % value = app.FOVkySlider.Value;
            app.lampInProgress('Calculating...');
            if app.FOVkxSlider.Value > 31 && app.FOVkxSlider.Value < 35
                app.FOVkxSlider.Value = 33.3333333333;
            elseif app.FOVkxSlider.Value < ceil(app.fullDataSize(2)/200)
                app.FOVkxSlider.Value = ceil(200/app.fullDataSize(2));
            else
                app.FOVkxSlider.Value = 2.5 * round(app.FOVkxSlider.Value/2.5);
            end
            if app.FOVkySlider.Value > 31 && app.FOVkySlider.Value < 35
                app.FOVkySlider.Value = 33.3333333333;
            elseif app.FOVkySlider.Value < ceil(app.fullDataSize(1)/200)
                app.FOVkySlider.Value = ceil(200/app.fullDataSize(1));
            else
                app.FOVkySlider.Value = 2.5 * round(app.FOVkySlider.Value/2.5);
            end            
            app.dataUpdate();
            app.displayData();
            app.lampDone();
        end

        % Value changed function: imageLevel, imageWindow, 
        % showOriginalCheckBox
        function refreshImage(app, event)
            % value = app.showOriginalCheckBox.Value;
            app.displayImageData();
        end

        % Value changed function: kspaceLevel, kspaceWindow
        function refreshKspace(app, event)
            % value = app.kspaceWindow.Value;
            app.displayKspaceData();
        end

        % Value changed function: UsezerofillCheckBox
        function UsezerofillCheckBoxValueChanged(app, event)
            % value = app.UsezerofillCheckBox.Value;
            app.lampInProgress('Calculating...');
            app.dataUpdate();
            app.displayData();
            app.lampDone();
        end

        % Value changed function: kycropModeSwitch
        function kycropModeSwitchValueChanged(app, event)
            % value = app.kycropModeSwitch.Value;
            app.lampInProgress('Calculating...');
            if strcmp(app.kycropModeSwitch.Value,'Bottom')
                app.kxcropModeSwitch.Value = 'Symmetric';
                app.kxcropModeSwitch.Enable = 'Off';
            else
                app.kxcropModeSwitch.Enable = 'On';
            end
                app.dataUpdate();
            app.displayData();
            app.lampDone();            
        end

        % Value changed function: kxcropModeSwitch
        function kxcropModeSwitchValueChanged(app, event)
            % value = app.kxcropModeSwitch.Value;
            app.lampInProgress('Calculating...');
            if strcmp(app.kxcropModeSwitch.Value,'Left')
                app.kycropModeSwitch.Value = 'Symmetric';
                app.kycropModeSwitch.Enable = 'Off';
            else
                app.kycropModeSwitch.Enable = 'On';
            end       
            app.dataUpdate();
            app.displayData();
            app.lampDone();                
        end

        % Value changed function: UseFermiCheckBox
        function UseFermiCheckBoxValueChanged(app, event)
            % value = app.UseFermiCheckBox.Value;            
            app.lampInProgress('Calculating...');
            app.dataUpdate();
            app.displayData();
            app.lampDone();
        end

        % Selection changed function: ReconMethodButtonGroup
        function ReconMethodButtonGroupSelectionChanged(app, event)
            %selectedButton = app.ReconMethodButtonGroup.SelectedObject;
            app.lampInProgress('Calculating...');
            app.dataUpdate();
            app.displayData();
            app.lampDone();
        end

        % Value changed function: QuitButton
        function QuitButtonPushed(app, event)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.kspaceExplorer = uifigure;
            app.kspaceExplorer.Name = 'k-space Explorer';
            app.UI_kspace = uiaxes(app.kspaceExplorer);
            app.UI_kspace.XLim = [1 512];
            app.UI_kspace.YLim = [1 512];
            app.UI_kspace.YDir = 'reverse';
            app.UI_kspace.XTick = [];
            app.UI_kspace.YTick = [];
            app.FOVxSliderLabel = uilabel(app.kspaceExplorer);
            app.FOVxSliderLabel.Text = 'FOV x [%]';
            app.FOVxSlider = uislider(app.kspaceExplorer);
            app.FOVxSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.FOVxSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.FOVxSlider.Value = 100;
            app.KspaceExplorerLabel = uilabel(app.kspaceExplorer);
            app.KspaceExplorerLabel.Text = 'K-space Explorer';
            app.FOVkySliderLabel = uilabel(app.kspaceExplorer);
            app.FOVkySliderLabel.Text = 'FOV ky [%]';
            app.FOVkySlider = uislider(app.kspaceExplorer);
            app.FOVkySlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.FOVkySlider.ValueChangedFcn = createCallbackFcn(app, @kSliderValueChanged, true);
            app.FOVkySlider.Value = 100;
            app.FOVySliderLabel = uilabel(app.kspaceExplorer);
            app.FOVySliderLabel.Text = 'FOV y [%]';
            app.FOVySlider = uislider(app.kspaceExplorer);
            app.FOVySlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.FOVySlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.FOVySlider.Value = 100;
            app.UI_kspace_zoom = uiaxes(app.kspaceExplorer);
            title(app.UI_kspace_zoom, 'k-space center')
            app.UI_kspace_zoom.XTick = [241 256 272];
            app.UI_kspace_zoom.YTick = [241 256 272];
            app.FOVkxSliderLabel = uilabel(app.kspaceExplorer);
            app.FOVkxSliderLabel.Text = 'FOV kx [%]';
            app.FOVkxSlider = uislider(app.kspaceExplorer);
            app.FOVkxSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.FOVkxSlider.ValueChangedFcn = createCallbackFcn(app, @kSliderValueChanged, true);
            app.FOVkxSlider.Value = 100;
            app.UI_ispace = uiaxes(app.kspaceExplorer);
            app.UI_ispace.XLim = [1 512];
            app.UI_ispace.YLim = [1 512];
            app.UI_ispace.YDir = 'reverse';
            app.UI_ispace.XTick = [];
            app.UI_ispace.XTickLabel = '';
            app.UI_ispace.YTick = [];
            app.UI_ispace.YTickLabel = '';
            app.UI_ispace_zoom = uiaxes(app.kspaceExplorer);
            title(app.UI_ispace_zoom, 'image center')
            app.UI_ispace_zoom.XTick = [241 256 272];
            app.UI_ispace_zoom.YTick = [241 256 272];
            app.KspacedisplayButtonGroup = uibuttongroup(app.kspaceExplorer);
            app.KspacedisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @KspacedisplayButtonGroupSelectionChanged, true);
            app.KspacedisplayButtonGroup.Title = 'K-space display';
            app.kMagnitudeButton = uiradiobutton(app.KspacedisplayButtonGroup);
            app.kMagnitudeButton.Text = 'Magnitude';
            app.kMagnitudeButton.Value = true;
            app.kPhaseButton = uiradiobutton(app.KspacedisplayButtonGroup);
            app.kPhaseButton.Text = 'Phase';
            app.kMagPhaseButton = uiradiobutton(app.KspacedisplayButtonGroup);
            app.kMagPhaseButton.Text = 'Mag. + Phase';
            app.kRealButton = uiradiobutton(app.KspacedisplayButtonGroup);
            app.kRealButton.Text = 'Real';
            app.kImagButton = uiradiobutton(app.KspacedisplayButtonGroup);
            app.kImagButton.Text = 'Imag';
            app.ImagedisplayButtonGroup = uibuttongroup(app.kspaceExplorer);
            app.ImagedisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImagedisplayButtonGroupSelectionChanged, true);
            app.ImagedisplayButtonGroup.Title = 'Image display';
            app.MagnitudeButton = uiradiobutton(app.ImagedisplayButtonGroup);
            app.MagnitudeButton.Text = 'Magnitude';
            app.MagnitudeButton.Value = true;
            app.PhaseButton = uiradiobutton(app.ImagedisplayButtonGroup);
            app.PhaseButton.Text = 'Phase';
            app.MagPhaseButton = uiradiobutton(app.ImagedisplayButtonGroup);
            app.MagPhaseButton.Text = 'Mag. + Phase';
            app.RealButton = uiradiobutton(app.ImagedisplayButtonGroup);
            app.RealButton.Text = 'Real';
            app.ImagButton = uiradiobutton(app.ImagedisplayButtonGroup);
            app.ImagButton.Text = 'Imag';
            app.TabGroup = uitabgroup(app.kspaceExplorer);
            app.DataselectionTab = uitab(app.TabGroup);
            app.DataselectionTab.Title = 'Data selection';
            app.ChooseimageLabel = uilabel(app.DataselectionTab);
            app.ChooseimageLabel.Text = 'Choose image';
            app.ChooseimageDropDown = uidropdown(app.DataselectionTab);
            app.ChooseimageDropDown.Items = {'<custom image>'};
            app.ChooseimageDropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.ChooseimageDropDown.Value = '<custom image>';
            app.PhaseEncodingdirButtonGroup = uibuttongroup(app.DataselectionTab);
            app.PhaseEncodingdirButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PhaseEncodingdirButtonGroupSelectionChanged, true);
            app.PhaseEncodingdirButtonGroup.Title = 'Phase encoding dir.';
            app.PhaseEncodingVerticalButton = uitogglebutton(app.PhaseEncodingdirButtonGroup);
            app.PhaseEncodingVerticalButton.Text = 'Vertical (Y)';
            app.PhaseEncodingVerticalButton.Value = true;
            app.PhaseEncodingHorizontalButton = uitogglebutton(app.PhaseEncodingdirButtonGroup);
            app.PhaseEncodingHorizontalButton.Text = 'Horizontal (X)';
            app.ReconstructionoptionsTab = uitab(app.TabGroup);
            app.ReconstructionoptionsTab.Title = 'Reconstruction options';
            app.AddnoiseLabel = uilabel(app.ReconstructionoptionsTab);
            app.AddnoiseLabel.Text = 'Add noise';
            app.AddnoiseKnob = uiknob(app.ReconstructionoptionsTab, 'continuous');
            app.AddnoiseKnob.ValueChangedFcn = createCallbackFcn(app, @AddnoiseKnobValueChanged, true);
            app.UsezerofillCheckBox = uicheckbox(app.ReconstructionoptionsTab);
            app.UsezerofillCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsezerofillCheckBoxValueChanged, true);
            app.UsezerofillCheckBox.Text = 'Use zerofilling (fill up k-space with zeros up to 512x512)';
            app.UseFermiCheckBox = uicheckbox(app.ReconstructionoptionsTab);
            app.UseFermiCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseFermiCheckBoxValueChanged, true);
            app.UseFermiCheckBox.Text = 'Use Fermi filtering (make smooth at k-space edge)';
            app.ParallelimagingTab = uitab(app.TabGroup);
            app.ParallelimagingTab.Title = 'Parallel imaging';
            app.AccelerationfactorRKnobLabel = uilabel(app.ParallelimagingTab);
            app.AccelerationfactorRKnobLabel.Text = 'Acceleration factor R';
            app.accelerationFactorRKnob = uiknob(app.ParallelimagingTab, 'discrete');
            app.accelerationFactorRKnob.Items = {'1', '2', '3', '4', '5', '6'};
            app.accelerationFactorRKnob.ValueChangedFcn = createCallbackFcn(app, @accelerationFactorRKnobValueChanged, true);
            app.accelerationFactorRKnob.Value = '1';
            app.centerstripSliderLabel = uilabel(app.ParallelimagingTab);
            app.centerstripSliderLabel.Text = 'center strip [%]';
            app.centerstripSlider = uislider(app.ParallelimagingTab);
            app.centerstripSlider.Limits = [0 30];
            app.centerstripSlider.MajorTicks = [0 5 10 15 20 25 30];
            app.centerstripSlider.ValueChangedFcn = createCallbackFcn(app, @centerstripSliderValueChanged, true);
            app.ApplyGRAPPAweightsLabel = uilabel(app.ParallelimagingTab);
            app.ApplyGRAPPAweightsLabel.Text = 'Apply GRAPPA weights';
            app.ApplyGRAPPAweightsSwitch = uiswitch(app.ParallelimagingTab, 'toggle');
            app.ApplyGRAPPAweightsSwitch.ValueChangedFcn = createCallbackFcn(app, @ParallelimagingSwitchValueChanged, true);
            app.InformationPanel = uipanel(app.kspaceExplorer);
            app.InformationPanel.Title = 'Information';
            app.ScanTimeLabel = uilabel(app.InformationPanel);
            app.ScanTimeLabel.Text = 'Scan Time [%]:  100';
            app.RelativeSNRLabel = uilabel(app.InformationPanel);
            app.RelativeSNRLabel.Text = 'Relative SNR [%]: 100';
            app.acqMatrixSizeLabel = uilabel(app.InformationPanel);
            app.acqMatrixSizeLabel.Text = 'Acq. matrix: 512x512';
            app.recMatrixSizeLabel = uilabel(app.InformationPanel);
            app.recMatrixSizeLabel.Text = 'Rec. matrix: 512x512';
            app.SNRefficiencyLabel = uilabel(app.InformationPanel);
            app.SNRefficiencyLabel.Text = 'SNR efficiency [%]: 100';
            app.AuthorTextArea = uitextarea(app.kspaceExplorer);
            app.AuthorTextArea.Editable = 'off';
            app.AuthorTextArea.Value = {'May 8, 2018 '; 'Neuro MR Physics Group, Karolinska University Hospital '; 'stefan.skare@neuroradkarolinska.se '};
            app.WindowLevelPanel = uipanel(app.kspaceExplorer);
            app.WindowLevelPanel.Title = 'Window/Level';
            app.WSliderLabel = uilabel(app.WindowLevelPanel);
            app.WSliderLabel.Text = 'W';
            app.imageWindow = uislider(app.WindowLevelPanel);
            app.imageWindow.Limits = [0 1.7];
            app.imageWindow.MajorTicks = [];
            app.imageWindow.MajorTickLabels = {};
            app.imageWindow.ValueChangedFcn = createCallbackFcn(app, @refreshImage, true);
            app.imageWindow.MinorTicks = [];
            app.imageWindow.Value = 1;
            app.LSliderLabel = uilabel(app.WindowLevelPanel);
            app.LSliderLabel.Text = 'L';
            app.imageLevel = uislider(app.WindowLevelPanel);
            app.imageLevel.Limits = [-0.4 0.4];
            app.imageLevel.MajorTicks = [];
            app.imageLevel.MajorTickLabels = {};
            app.imageLevel.ValueChangedFcn = createCallbackFcn(app, @refreshImage, true);
            app.imageLevel.MinorTicks = [];
            app.kWindowLevelPanel = uipanel(app.kspaceExplorer);
            app.kWindowLevelPanel.Title = 'Window/Level';
            app.WSlider_2Label = uilabel(app.kWindowLevelPanel);
            app.WSlider_2Label.Text = 'W';
            app.kspaceWindow = uislider(app.kWindowLevelPanel);
            app.kspaceWindow.Limits = [0 1.7];
            app.kspaceWindow.MajorTicks = [];
            app.kspaceWindow.MajorTickLabels = {};
            app.kspaceWindow.ValueChangedFcn = createCallbackFcn(app, @refreshKspace, true);
            app.kspaceWindow.MinorTicks = [];
            app.kspaceWindow.Value = 1;
            app.LSlider_2Label = uilabel(app.kWindowLevelPanel);
            app.LSlider_2Label.Text = 'L';
            app.kspaceLevel = uislider(app.kWindowLevelPanel);
            app.kspaceLevel.Limits = [-0.4 0.4];
            app.kspaceLevel.MajorTicks = [];
            app.kspaceLevel.MajorTickLabels = {};
            app.kspaceLevel.ValueChangedFcn = createCallbackFcn(app, @refreshKspace, true);
            app.kspaceLevel.MinorTicks = [];
            app.showOriginalCheckBox = uicheckbox(app.kspaceExplorer);
            app.showOriginalCheckBox.ValueChangedFcn = createCallbackFcn(app, @refreshImage, true);
            app.showOriginalCheckBox.Text = 'Show original image';
            app.kycropModeSwitch = uiswitch(app.kspaceExplorer, 'slider');
            app.kycropModeSwitch.Items = {'Bottom', 'Symmetric'};
            app.kycropModeSwitch.ValueChangedFcn = createCallbackFcn(app, @kycropModeSwitchValueChanged, true);
            app.kycropModeSwitch.Value = 'Symmetric';
            app.CalculationstatusLampLabel = uilabel(app.kspaceExplorer);
            app.CalculationstatusLampLabel.Text = 'Calculation status';
            app.CalculationstatusLamp = uilamp(app.kspaceExplorer);
            app.kspace1mLabel = uilabel(app.kspaceExplorer);
            app.kspace1mLabel.Text = 'k-space [1/m]';
            app.imagemLabel = uilabel(app.kspaceExplorer);
            app.imagemLabel.Text = 'image [m]';
            app.kxcropModeSwitch = uiswitch(app.kspaceExplorer, 'slider');
            app.kxcropModeSwitch.Items = {'Left', 'Symmetric'};
            app.kxcropModeSwitch.ValueChangedFcn = createCallbackFcn(app, @kxcropModeSwitchValueChanged, true);
            app.kxcropModeSwitch.Value = 'Symmetric';
            app.ReconMethodButtonGroup = uibuttongroup(app.kspaceExplorer);
            app.ReconMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ReconMethodButtonGroupSelectionChanged, true);
            app.ReconMethodButtonGroup.Title = 'Recon Method';
            app.iFFTButton = uitogglebutton(app.ReconMethodButtonGroup);
            app.iFFTButton.Text = 'iFFT';
            app.iFFTButton.Value = true;
            app.HomodyneButton = uitogglebutton(app.ReconMethodButtonGroup);
            app.HomodyneButton.Enable = 'off';
            app.HomodyneButton.Text = 'Homodyne';
            app.CouldnotfindTextAreaLabel = uilabel(app.kspaceExplorer);
            app.CouldnotfindTextAreaLabel.Enable = 'off';
            app.CouldnotfindTextAreaLabel.Text = 'Could not find';
            app.CouldnotfindTextArea = uitextarea(app.kspaceExplorer);
            app.CouldnotfindTextArea.Editable = 'off';
            app.CouldnotfindTextArea.Enable = 'off';
            app.QuitButton = uibutton(app.kspaceExplorer, 'state');
            app.QuitButton.ValueChangedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = kspace_explorer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.kspaceExplorer)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.kspaceExplorer)
        end
    end
end