classdef mrcontrast_explorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        contrastExplorer             matlab.ui.Figure
        MRContrastExplorerLabel      matlab.ui.control.Label
        AuthorTextArea               matlab.ui.control.TextArea
        UI_image                     matlab.ui.control.UIAxes
        ChooseimageDropDownLabel     matlab.ui.control.Label
        ChooseimageDropDown          matlab.ui.control.DropDown
        SequenceparametersPanel      matlab.ui.container.Panel
        Label                        matlab.ui.control.Label
        TEGauge                      matlab.ui.control.LinearGauge
        TEmsLabel                    matlab.ui.control.Label
        TESlider                     matlab.ui.control.Slider
        TRmsSliderLabel_2            matlab.ui.control.Label
        TRSlider                     matlab.ui.control.Slider
        TImsLabel                    matlab.ui.control.Label
        TISlider                     matlab.ui.control.Slider
        Label_2                      matlab.ui.control.Label
        TRGauge                      matlab.ui.control.LinearGauge
        Label_3                      matlab.ui.control.Label
        TIGauge                      matlab.ui.control.LinearGauge
        FlipAngledegKnobLabel        matlab.ui.control.Label
        FlipAngleKnob                matlab.ui.control.Knob
        NoiselevelKnobLabel          matlab.ui.control.Label
        AddnoiseKnob                 matlab.ui.control.Knob
        StaticfieldB0ReceiverbandwidthrBWPanel  matlab.ui.container.Panel
        ChemicalShiftmmGauge_2Label  matlab.ui.control.Label
        ChemicalShiftGauge           matlab.ui.control.SemicircularGauge
        BandwidthLabel               matlab.ui.control.Label
        BandwidthKnob                matlab.ui.control.Knob
        B0ButtonGroup                matlab.ui.container.ButtonGroup
        Button15T                    matlab.ui.control.RadioButton
        Button3T                     matlab.ui.control.RadioButton
        SequencefamilyButtonGroup    matlab.ui.container.ButtonGroup
        SpinEchoT2Button             matlab.ui.control.RadioButton
        GradientEchoT2Button         matlab.ui.control.RadioButton
        FatSuppressionDixonCheckBox  matlab.ui.control.CheckBox
        InversionCheckBox            matlab.ui.control.CheckBox
        WindowLevelPanel             matlab.ui.container.Panel
        WSliderLabel                 matlab.ui.control.Label
        imageWindow                  matlab.ui.control.Slider
        LSliderLabel                 matlab.ui.control.Label
        imageLevel                   matlab.ui.control.Slider
        TETRIntensityconstantforButtonGroup  matlab.ui.container.ButtonGroup
        constWMButton                matlab.ui.control.RadioButton
        constGMButton                matlab.ui.control.RadioButton
        constFatButton               matlab.ui.control.RadioButton
        AutoIntensitySwitch          matlab.ui.control.Switch
        CouldnotfindTextAreaLabel    matlab.ui.control.Label
        CouldnotfindTextArea         matlab.ui.control.TextArea
        QuitButton                   matlab.ui.control.StateButton
    end


    properties (Access = private)
        weightedImage = rand(512);
        imageDataMenu = []
        wat = []
        fat = []
        R1 = []
        R2 = []
        R2star = []
        TE = []
        TR = []
        TI = []
        FA = []
        adjust2R1 = 1;
        adjust2R2 = 10;
        FatSuppressed = false;
        B0 = 1.5;
    end

    methods (Access = private)
    
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            [mfilepth,fname] = fileparts(which(mfilename));
            pth = [];
            
            if ispc
                userdir = getenv('USERPROFILE'); 
            else
                userdir= getenv('HOME'); 
            end
            
            pths = {mfilepth, fullfile(userdir,'Downloads'), fullfile(userdir,'Desktop'), userdir};
            
            for j = 1:numel(pths)
                if exist(fullfile(pths{j},[fname,'.mat']), 'file')
                    pth = pths{j}; break;
                end
            end
                       
            if isempty(pth)
                imageDataFileContent.imageDataMenu.description = 'Couldn''t find contast_explorer.mat';
                imageDataFileContent.imageDataMenu.PD = rand(512);
                imageDataFileContent.imageDataMenu.T1 = rand(512);
                imageDataFileContent.imageDataMenu.T2 = rand(512);
                imageDataFileContent.imageDataMenu.T2star = rand(512);
                app.CouldnotfindTextArea.Visible = 'on';
                app.CouldnotfindTextArea.Value = sprintf('%s ', string(pths));
                app.CouldnotfindTextAreaLabel.Visible = 'on';
                app.CouldnotfindTextAreaLabel.Text = sprintf('Could not find %s', [fname,'.mat']);                
            else
                imageDataFileContent = load(fullfile(pth,[fname,'.mat']));
                app.CouldnotfindTextArea.Visible = 'off';
                app.CouldnotfindTextAreaLabel.Visible = 'off';
            end
            app.imageDataMenu = imageDataFileContent.imageDataMenu;
            
            % setup image data menu
            app.ChooseimageDropDown.Items = {};
            for j = 1:numel(app.imageDataMenu)
                app.ChooseimageDropDown.Items{j} = app.imageDataMenu(j).description;
            end
            app.ChooseimageDropDown.Value = app.ChooseimageDropDown.Items{1};

            app.selectMaps();
            app.updateLabels('TE', app.TESlider.Value);
            app.updateLabels('TR', app.TRSlider.Value);
            app.dataUpdate();
            app.displayImageData();
            
        end

        % Value changing function: TRSlider
        function TRSliderValueChanging(app, event)
            changingValue = event.Value;
            app.updateLabels('TR', changingValue);
            app.dataUpdate();
            app.displayImageData();
        end

        % Value changed function: ChooseimageDropDown
        function ChooseimageDropDownValueChanged(app, event)
            % value = app.ChooseimageDropDown.Value;
            app.selectMaps();
            app.dataUpdate();
            app.displayImageData();
        end

        % Value changed function: InversionCheckBox
        function InversionCheckBoxValueChanged(app, event)
            if app.InversionCheckBox.Value
                app.TISlider.Enable = 'on';
                app.TIGauge.Enable = 'on';
                app.Label_3.Enable = 'on';
                app.TImsLabel.Enable = 'on';
            else
                app.TISlider.Enable = 'off';
                app.TIGauge.Enable = 'off';
                app.Label_3.Enable = 'off';
                app.TImsLabel.Enable = 'off';
            end
            app.dataUpdate();
            app.displayImageData();
        end

        % Value changing function: imageWindow
        function imageWindowValueChanging(app, event)
            changingValue = event.Value;
            app.imageWindow.Value = changingValue;
            app.displayImageData();
        end

        % Value changing function: imageLevel
        function imageLevelValueChanging(app, event)
            changingValue = event.Value;
            app.imageLevel.Value = changingValue;
            app.displayImageData();
        end

        % Selection changed function: SequencefamilyButtonGroup
        function SequencefamilyButtonGroupSelectionChanged(app, event)
            % selectedButton = app.SequencefamilyButtonGroup.SelectedObject;
            if app.SpinEchoT2Button.Value
                app.FlipAngleKnob.Enable = 'off';
                app.FlipAngledegKnobLabel.Enable = 'off';
                app.FlipAngleKnob.Value = 90;
                app.InversionCheckBox.Enable = 'on';
                if app.InversionCheckBox.Value
                    app.TISlider.Enable = 'on';
                    app.TIGauge.Enable = 'on';
                    app.TImsLabel.Enable = 'on';
                    app.Label_3.Enable = 'on';
                end
            elseif app.GradientEchoT2Button.Value
                app.FlipAngleKnob.Enable = 'on';
                app.FlipAngledegKnobLabel.Enable = 'on';
                app.TISlider.Enable = 'off';
                app.TIGauge.Enable = 'off';
                app.TImsLabel.Enable = 'off';
                app.Label_3.Enable = 'off';
                app.InversionCheckBox.Value = 0;
                app.InversionCheckBox.Enable = 'off';
            end
            app.dataUpdate();
            app.displayImageData();
        end

        % Value changed function: AutoIntensitySwitch
        function AutoIntensitySwitchValueChanged(app, event)
            % value = app.AutoIntensitySwitch.Value;
            app.dataUpdate();
            app.displayImageData();
        end

        % Callback function
        function B0SwitchValueChanged(app, event)
            if app.Button15T.Value
                app.B0 = 1.5;
            elseif app.Button3T.Value
                app.B0 = 3.0;
            end
            app.dataUpdate();
            app.displayImageData();            
        end

        % Value changing function: TISlider
        function TISliderValueChanging(app, event)
            changingValue = event.Value;
            app.updateLabels('TI', changingValue);
            app.dataUpdate();
            app.displayImageData();
        end

        % Value changing function: TESlider
        function TESliderValueChanging(app, event)
            changingValue = event.Value;
            app.updateLabels('TE', changingValue);
            app.dataUpdate();
            app.displayImageData();
        end

        % Value changing function: FlipAngleKnob
        function FlipAngleKnobValueChanging(app, event)
            changingValue = event.Value;
            app.FlipAngleKnob.Value =changingValue;
            app.dataUpdate();
            app.displayImageData();  
        end

        % Value changing function: AddnoiseKnob
        function AddnoiseKnobValueChanging(app, event)
            changingValue = event.Value;
            app.AddnoiseKnob.Value = changingValue;
            app.dataUpdate();
            app.displayImageData();       
        end

        % Value changing function: BandwidthKnob
        function BandwidthKnobValueChanging(app, event)
            changingValue = event.Value;
            if changingValue >= 2
                app.BandwidthKnob.Value = changingValue;
            else
                app.BandwidthKnob.Value = 2;
            end
            app.dataUpdate();
            app.displayImageData();
        end

        % Value changed function: QuitButton
        function QuitButtonValueChanged(app, event)
            % value = app.QuitButton.Value;
            contrastExplorerCloseRequest(app, event)
        end

        % Close request function: contrastExplorer
        function contrastExplorerCloseRequest(app, event)
            delete(app)          
        end

        % Value changed function: FatSuppressionDixonCheckBox
        function FatSuppressionCheckBoxValueChanged(app, event)
            app.FatSuppressed = app.FatSuppressionDixonCheckBox.Value;
            app.dataUpdate();
            app.displayImageData();
        end

        % Selection changed function: B0ButtonGroup
        function B0ButtonGroupSelectionChanged(app, event)
            if app.Button15T.Value
                app.B0 = 1.5;
            elseif app.Button3T.Value
                app.B0 = 3.0;
            end
            app.dataUpdate();
            app.displayImageData();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.contrastExplorer = uifigure;
            app.contrastExplorer.Name = 'MR Contrast Explorer';
            app.contrastExplorer.CloseRequestFcn = createCallbackFcn(app, @contrastExplorerCloseRequest, true);
            app.MRContrastExplorerLabel = uilabel(app.contrastExplorer);
            app.MRContrastExplorerLabel.Text = 'MR Contrast Explorer';
            app.AuthorTextArea = uitextarea(app.contrastExplorer);
            app.AuthorTextArea.Editable = 'off';
            app.AuthorTextArea.Value = {'Mayl 8, 2018 '; 'Neuro MR Physics Group, Karolinska University Hospital '; 'johan.berglund & stefan.skare @neuroradkarolinska.se '};
            app.UI_image = uiaxes(app.contrastExplorer);
            app.UI_image.XLim = [1 512];
            app.UI_image.YLim = [1 512];
            app.UI_image.YDir = 'reverse';
            app.UI_image.XTick = [];
            app.UI_image.XTickLabel = {'['};
            app.UI_image.YTick = [];
            app.UI_image.YTickLabel = {'['};
            app.ChooseimageDropDownLabel = uilabel(app.contrastExplorer);
            app.ChooseimageDropDownLabel.Text = 'Choose image';
            app.ChooseimageDropDown = uidropdown(app.contrastExplorer);
            app.ChooseimageDropDown.Items = {'<custom image>'};
            app.ChooseimageDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseimageDropDownValueChanged, true);
            app.ChooseimageDropDown.Value = '<custom image>';
            app.SequenceparametersPanel = uipanel(app.contrastExplorer);
            app.SequenceparametersPanel.Title = 'Sequence parameters';
            app.Label = uilabel(app.SequenceparametersPanel);
            app.Label.Text = '0';
            app.TEGauge = uigauge(app.SequenceparametersPanel, 'linear');
            app.TEGauge.Limits = [0 3];
            app.TEGauge.MajorTickLabels = {'0', '2', '4', '6', '8', '10', '20', '40', '60', '80', '100', '200', '400', '600', '800', '1s'};
            app.TEGauge.Value = 1;
            app.TEmsLabel = uilabel(app.SequenceparametersPanel);
            app.TEmsLabel.Text = 'TE [ms]';
            app.TESlider = uislider(app.SequenceparametersPanel);
            app.TESlider.Limits = [0 3];
            app.TESlider.MajorTicks = [];
            app.TESlider.MajorTickLabels = {};
            app.TESlider.ValueChangingFcn = createCallbackFcn(app, @TESliderValueChanging, true);
            app.TESlider.MinorTicks = [];
            app.TESlider.Value = 1;
            app.TRmsSliderLabel_2 = uilabel(app.SequenceparametersPanel);
            app.TRmsSliderLabel_2.Text = 'TR [ms]';
            app.TRSlider = uislider(app.SequenceparametersPanel);
            app.TRSlider.Limits = [0 3];
            app.TRSlider.MajorTicks = [];
            app.TRSlider.MajorTickLabels = {};
            app.TRSlider.ValueChangingFcn = createCallbackFcn(app, @TRSliderValueChanging, true);
            app.TRSlider.MinorTicks = [];
            app.TRSlider.Value = 3;
            app.TImsLabel = uilabel(app.SequenceparametersPanel);
            app.TImsLabel.Enable = 'off';
            app.TImsLabel.Text = 'TI [ms]';
            app.TISlider = uislider(app.SequenceparametersPanel);
            app.TISlider.Limits = [0 3];
            app.TISlider.MajorTicks = [];
            app.TISlider.MajorTickLabels = {};
            app.TISlider.ValueChangingFcn = createCallbackFcn(app, @TISliderValueChanging, true);
            app.TISlider.MinorTicks = [];
            app.TISlider.Enable = 'off';
            app.TISlider.Value = 1;
            app.Label_2 = uilabel(app.SequenceparametersPanel);
            app.Label_2.Text = '10000';
            app.TRGauge = uigauge(app.SequenceparametersPanel, 'linear');
            app.TRGauge.Limits = [0 3];
            app.TRGauge.MajorTickLabels = {'0', '20', '40', '60', '80', '100', '200', '400', '600', '800', '1s', '2s', '4s', '6s', '8s', '10s'};
            app.TRGauge.Value = 1;
            app.Label_3 = uilabel(app.SequenceparametersPanel);
            app.Label_3.Enable = 'off';
            app.Label_3.Text = '450';
            app.TIGauge = uigauge(app.SequenceparametersPanel, 'linear');
            app.TIGauge.Limits = [0 3];
            app.TIGauge.MajorTickLabels = {'0', '20', '40', '60', '80', '100', '200', '400', '600', '800', '1s', '2s', '4s', '6s', '8s', '10s'};
            app.TIGauge.Enable = 'off';
            app.TIGauge.Value = 1;
            app.FlipAngledegKnobLabel = uilabel(app.SequenceparametersPanel);
            app.FlipAngledegKnobLabel.Enable = 'off';
            app.FlipAngledegKnobLabel.Text = 'Flip Angle [deg]';
            app.FlipAngleKnob = uiknob(app.SequenceparametersPanel, 'continuous');
            app.FlipAngleKnob.Limits = [0 90];
            app.FlipAngleKnob.MajorTicks = [0 15 30 45 60 75 90];
            app.FlipAngleKnob.ValueChangingFcn = createCallbackFcn(app, @FlipAngleKnobValueChanging, true);
            app.FlipAngleKnob.Enable = 'off';
            app.FlipAngleKnob.Value = 90;
            app.NoiselevelKnobLabel = uilabel(app.SequenceparametersPanel);
            app.NoiselevelKnobLabel.Text = 'Noise level';
            app.AddnoiseKnob = uiknob(app.SequenceparametersPanel, 'continuous');
            app.AddnoiseKnob.Limits = [0 1];
            app.AddnoiseKnob.MajorTickLabels = {'', '', '', '', '', ''};
            app.AddnoiseKnob.ValueChangingFcn = createCallbackFcn(app, @AddnoiseKnobValueChanging, true);
            app.AddnoiseKnob.MinorTicks = [];
            app.AddnoiseKnob.Value = 0.05;
            app.StaticfieldB0ReceiverbandwidthrBWPanel = uipanel(app.SequenceparametersPanel);
            app.StaticfieldB0ReceiverbandwidthrBWPanel.Title = 'Static field (B0) & Receiver bandwidth (rBW)';
            app.ChemicalShiftmmGauge_2Label = uilabel(app.StaticfieldB0ReceiverbandwidthrBWPanel);
            app.ChemicalShiftmmGauge_2Label.Text = 'Chemical Shift [mm]';
            app.ChemicalShiftGauge = uigauge(app.StaticfieldB0ReceiverbandwidthrBWPanel, 'semicircular');
            app.ChemicalShiftGauge.Limits = [0 20];
            app.BandwidthLabel = uilabel(app.StaticfieldB0ReceiverbandwidthrBWPanel);
            app.BandwidthLabel.Text = 'Bandwidth';
            app.BandwidthKnob = uiknob(app.StaticfieldB0ReceiverbandwidthrBWPanel, 'continuous');
            app.BandwidthKnob.Limits = [0 64];
            app.BandwidthKnob.ValueChangingFcn = createCallbackFcn(app, @BandwidthKnobValueChanging, true);
            app.BandwidthKnob.Value = 32;
            app.B0ButtonGroup = uibuttongroup(app.StaticfieldB0ReceiverbandwidthrBWPanel);
            app.B0ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @B0ButtonGroupSelectionChanged, true);
            app.B0ButtonGroup.Title = 'B0 [T]';
            app.Button15T = uiradiobutton(app.B0ButtonGroup);
            app.Button15T.Text = '1.5';
            app.Button15T.Value = true;
            app.Button3T = uiradiobutton(app.B0ButtonGroup);
            app.Button3T.Text = '3.0';
            app.SequencefamilyButtonGroup = uibuttongroup(app.contrastExplorer);
            app.SequencefamilyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SequencefamilyButtonGroupSelectionChanged, true);
            app.SequencefamilyButtonGroup.Title = 'Sequence family';
            app.SpinEchoT2Button = uiradiobutton(app.SequencefamilyButtonGroup);
            app.SpinEchoT2Button.Text = 'Spin Echo (T2)';
            app.SpinEchoT2Button.Value = true;
            app.GradientEchoT2Button = uiradiobutton(app.SequencefamilyButtonGroup);
            app.GradientEchoT2Button.Text = 'Gradient Echo (T2*)';
            app.FatSuppressionDixonCheckBox = uicheckbox(app.contrastExplorer);
            app.FatSuppressionDixonCheckBox.ValueChangedFcn = createCallbackFcn(app, @FatSuppressionCheckBoxValueChanged, true);
            app.FatSuppressionDixonCheckBox.Text = 'Fat Suppression (Dixon)';
            app.InversionCheckBox = uicheckbox(app.contrastExplorer);
            app.InversionCheckBox.ValueChangedFcn = createCallbackFcn(app, @InversionCheckBoxValueChanged, true);
            app.InversionCheckBox.Text = 'Inversion (IR)';
            app.WindowLevelPanel = uipanel(app.contrastExplorer);
            app.WindowLevelPanel.Title = 'Window/Level';
            app.WSliderLabel = uilabel(app.WindowLevelPanel);
            app.WSliderLabel.Text = 'W';
            app.imageWindow = uislider(app.WindowLevelPanel);
            app.imageWindow.Limits = [0 1.7];
            app.imageWindow.MajorTicks = [];
            app.imageWindow.MajorTickLabels = {};
            app.imageWindow.ValueChangingFcn = createCallbackFcn(app, @imageWindowValueChanging, true);
            app.imageWindow.MinorTicks = [];
            app.imageWindow.Value = 1;
            app.LSliderLabel = uilabel(app.WindowLevelPanel);
            app.LSliderLabel.Text = 'L';
            app.imageLevel = uislider(app.WindowLevelPanel);
            app.imageLevel.Limits = [-1 1];
            app.imageLevel.MajorTicks = [];
            app.imageLevel.MajorTickLabels = {};
            app.imageLevel.ValueChangingFcn = createCallbackFcn(app, @imageLevelValueChanging, true);
            app.imageLevel.MinorTicks = [];
            app.TETRIntensityconstantforButtonGroup = uibuttongroup(app.contrastExplorer);
            app.TETRIntensityconstantforButtonGroup.Title = 'TE & TR: Intensity constant for';
            app.constWMButton = uiradiobutton(app.TETRIntensityconstantforButtonGroup);
            app.constWMButton.Text = 'WM';
            app.constWMButton.Value = true;
            app.constGMButton = uiradiobutton(app.TETRIntensityconstantforButtonGroup);
            app.constGMButton.Text = 'GM';
            app.constFatButton = uiradiobutton(app.TETRIntensityconstantforButtonGroup);
            app.constFatButton.Text = 'Fat';
            app.AutoIntensitySwitch = uiswitch(app.TETRIntensityconstantforButtonGroup, 'slider');
            app.AutoIntensitySwitch.ValueChangedFcn = createCallbackFcn(app, @AutoIntensitySwitchValueChanged, true);
            app.AutoIntensitySwitch.Value = 'On';
            app.CouldnotfindTextAreaLabel = uilabel(app.contrastExplorer);
            app.CouldnotfindTextAreaLabel.Enable = 'off';
            app.CouldnotfindTextAreaLabel.Text = 'Could not find';
            app.CouldnotfindTextArea = uitextarea(app.contrastExplorer);
            app.CouldnotfindTextArea.Editable = 'off';
            app.CouldnotfindTextArea.Enable = 'off';
            app.QuitButton = uibutton(app.contrastExplorer, 'state');
            app.QuitButton.ValueChangedFcn = createCallbackFcn(app, @QuitButtonValueChanged, true);
            app.QuitButton.Text = 'Quit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mrcontrast_explorer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.contrastExplorer)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.contrastExplorer)
        end
    end
end